classdef Tree_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MapButton              matlab.ui.control.Button
        NearestButton          matlab.ui.control.Button
        RandomButton           matlab.ui.control.Button
        BostonTreeFinderLabel  matlab.ui.control.Label
        AddressEditField       matlab.ui.control.EditField
        AddressLabel           matlab.ui.control.Label
        CoordinatesLabel       matlab.ui.control.Label
        CoordinatesEditField   matlab.ui.control.EditField
        ButtonGroup_2          matlab.ui.container.ButtonGroup
        WalkingButton          matlab.ui.control.ToggleButton
        DrivingButton          matlab.ui.control.ToggleButton
        BicyclingButton        matlab.ui.control.ToggleButton
        DistancemetersLabel    matlab.ui.control.Label
        DistanceEditField      matlab.ui.control.EditField
        ButtonGroup_3          matlab.ui.container.ButtonGroup
        ManualButton           matlab.ui.control.ToggleButton
        AutomaticButton        matlab.ui.control.ToggleButton
        LatitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField     matlab.ui.control.NumericEditField
        LatitudeLabel          matlab.ui.control.Label
        LongitudeLabel         matlab.ui.control.Label
        MapButtonGroup         matlab.ui.container.ButtonGroup
        YesButton              matlab.ui.control.ToggleButton
        NoButton               matlab.ui.control.ToggleButton
        DynamicMapLabel        matlab.ui.control.Label
        ModeofTransportLabel   matlab.ui.control.Label
        LocationMethodLabel    matlab.ui.control.Label
        TravelTimeLabel        matlab.ui.control.Label
        TimeEditField          matlab.ui.control.EditField
        ButtonGroup_4          matlab.ui.container.ButtonGroup
        YesButton_2            matlab.ui.control.ToggleButton
        NoButton_2             matlab.ui.control.ToggleButton
        ShowTreeonMapLabel     matlab.ui.control.Label
    end


    methods (Access = private)

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)
            %Calls getData() to get info from data set
            data=getData();
            
            %Checks if user wants dynamic map
            selectedMap=app.MapButtonGroup.SelectedObject;
            MapType=selectedMap.Text;
            if (strcmp(MapType,"Yes"))
                %Calls drawMapDyanmic() if Yes
                drawMapDynamic(data);
            else
                %Calls drawMap() if No
                drawMap(data);
            end
        end

        % Button pushed function: NearestButton
        function NearestButtonPushed(app, event)
            %Calls getData() to get info from data set
            data=getData();
            
            %Checks how user wants location to be input 
            selectedLocation=app.ButtonGroup_3.SelectedObject;
            Location=selectedLocation.Text;
            if (strcmp(Location,"Automatic"))
                %Gets location through IP since Automatic is selected
                [LatO,LongO]=getLocation;
            else
                %Gets location from user input since Manual is selected
                LatO=app.LatitudeEditField.Value;
                LongO=app.LongitudeEditField.Value;
            end
            
                %Gets the Mode selected by user
                selectedMode=app.ButtonGroup_2.SelectedObject;
                Mode=selectedMode.Text;
                app.AddressEditField.Value= Mode;
                
                %Calls getTree()
                [LatN,LongN,Address,Distance,Time,URL]=getTree(data,LatO,LongO,lower(Mode));
                disp(LatN);
                disp(LongN);
                %Casts LatN to a string
                LatN=num2str(LatN);
                
                %Casts LongN to a string
                LongN=num2str(LongN);
                
                %Casts Distance to a string
                Distance=num2str(Distance);
                
                %Takes Coordinates returned by getTree() and formats them into one string
                Coordinates=strcat(LatN,",",LongN);
                
                %Sets Address Field to Address returned by getTree()
                app.AddressEditField.Value=Address{:};
                
                %Sets Coordinates Field to Coordinates
                app.CoordinatesEditField.Value=Coordinates;
                
                %Sets Distance Field to Distance returned by getTree()
                app.DistanceEditField.Value=Distance;
                
                %Sets Time Field to Time returned by getTree()
                app.TimeEditField.Value=Time;
                
            %Checks if user wants a map displayed
            selectedMapShowN=app.ButtonGroup_4.SelectedObject;
            mapShowN=selectedMapShowN.Text;
            if (strcmp(mapShowN,"Yes"))
                %Draws map with tree point and your point since Yes selected
                drawMapLast(data,LatO,LongO);      
            end
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            %Calls getData() to get info from data set
            data=getData();
            
            %Checks how user wants location to be input 
            selectedLocation=app.ButtonGroup_3.SelectedObject;
            Location=selectedLocation.Text;
            if (strcmp(Location,"Automatic"))
                %Gets location through IP since Automatic is selected
                [LatO,LongO]=getLocation;
            else
                %Gets location from user input since Manual is selected
                LatO=app.LatitudeEditField.Value;
                LongO=app.LongitudeEditField.Value;
            end 
            
            %Sets r to a random value from 1 to 200845
            r=randi([1,height(data)]);

            %Gets the Mode selected by user
            selected=app.ButtonGroup_2.SelectedObject;
            Mode=selected.Text;
            app.AddressEditField.Value= Mode;
            
            %Calls getTree()
            [LatN,LongN,Address,Distance,Time,URL]=getTree(data,LatO,LongO,lower(Mode),r);
            
            %Casts LatN to a string
            LatN=num2str(LatN);
            
            %Casts LongN to a string
            LongN=num2str(LongN);
            
            %Casts Distance to a string
            Distance=num2str(Distance);
            
            %Takes Coordinates returned by getTree() and formats them into one string
            Coordinates=strcat(LatN,",",LongN);
            
            %Sets Address Field to Address returned by getTree()
            app.AddressEditField.Value=Address{:};
            
            %Sets Coordinates Field to Coordinates
            app.CoordinatesEditField.Value=Coordinates;
            
            %Sets Distance Field to Distance returned by getTree()
            app.DistanceEditField.Value=Distance;
            
            %Sets Time Field to Time returned by getTree()
            app.TimeEditField.Value=Time;
            
            %Checks if user wants a map displayed
            selectedMapShowR=app.ButtonGroup_4.SelectedObject;
            mapShowR=selectedMapShowR.Text;
            if (strcmp(mapShowR,"Yes"))
                %Draws map with tree point and your point since Yes selected
                drawMapLast(data,LatO,LongO);      
            end
        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            %Checks to see if user has Automatic or Manual selected
            selectedButton = app.ButtonGroup_3.SelectedObject;
            Method=selectedButton.Text;
            if (strcmp(Method,"Automatic"))
                %If Automatic, then the edit fields are disabled
                app.LatitudeEditField.Enable = 'off';
                app.LongitudeEditField.Enable = 'off';
            else
                %If Manual, then the edit fields are enabled
                app.LatitudeEditField.Enable = 'on';
                app.LongitudeEditField.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MapButton = uibutton(app.UIFigure, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Text = 'Map';
            app.NearestButton = uibutton(app.UIFigure, 'push');
            app.NearestButton.ButtonPushedFcn = createCallbackFcn(app, @NearestButtonPushed, true);
            app.NearestButton.Text = 'Nearest';
            app.RandomButton = uibutton(app.UIFigure, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.BostonTreeFinderLabel = uilabel(app.UIFigure);
            app.BostonTreeFinderLabel.Text = 'Boston Tree Finder';
            app.AddressEditField = uieditfield(app.UIFigure, 'text');
            app.AddressEditField.Value = 'Address will display here';
            app.AddressLabel = uilabel(app.UIFigure);
            app.AddressLabel.Text = 'Address';
            app.CoordinatesLabel = uilabel(app.UIFigure);
            app.CoordinatesLabel.Text = 'Coordinates';
            app.CoordinatesEditField = uieditfield(app.UIFigure, 'text');
            app.CoordinatesEditField.Value = 'Coordinates will display here';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.WalkingButton = uitogglebutton(app.ButtonGroup_2);
            app.WalkingButton.Text = 'Walking';
            app.WalkingButton.Value = true;
            app.DrivingButton = uitogglebutton(app.ButtonGroup_2);
            app.DrivingButton.Text = 'Driving';
            app.BicyclingButton = uitogglebutton(app.ButtonGroup_2);
            app.BicyclingButton.Text = 'Bicycling';
            app.DistancemetersLabel = uilabel(app.UIFigure);
            app.DistancemetersLabel.Text = 'Distance (meters)';
            app.DistanceEditField = uieditfield(app.UIFigure, 'text');
            app.DistanceEditField.Value = 'Approximate Distance will display here';
            app.ButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.ManualButton = uitogglebutton(app.ButtonGroup_3);
            app.ManualButton.Text = 'Manual';
            app.AutomaticButton = uitogglebutton(app.ButtonGroup_3);
            app.AutomaticButton.Text = 'Automatic';
            app.AutomaticButton.Value = true;
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%.10f';
            app.LatitudeEditField.Enable = 'off';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%.10f';
            app.LongitudeEditField.Enable = 'off';
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude';
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude';
            app.MapButtonGroup = uibuttongroup(app.UIFigure);
            app.YesButton = uitogglebutton(app.MapButtonGroup);
            app.YesButton.Text = 'Yes';
            app.NoButton = uitogglebutton(app.MapButtonGroup);
            app.NoButton.Text = 'No';
            app.NoButton.Value = true;
            app.DynamicMapLabel = uilabel(app.UIFigure);
            app.DynamicMapLabel.Text = 'Dynamic Map';
            app.ModeofTransportLabel = uilabel(app.UIFigure);
            app.ModeofTransportLabel.Text = 'Mode of Transport';
            app.LocationMethodLabel = uilabel(app.UIFigure);
            app.LocationMethodLabel.Text = 'Location Method';
            app.TravelTimeLabel = uilabel(app.UIFigure);
            app.TravelTimeLabel.Text = 'Travel Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'text');
            app.TimeEditField.Value = 'Approximate Travel Time will display here';
            app.ButtonGroup_4 = uibuttongroup(app.UIFigure);
            app.YesButton_2 = uitogglebutton(app.ButtonGroup_4);
            app.YesButton_2.Text = 'Yes';
            app.YesButton_2.Value = true;
            app.NoButton_2 = uitogglebutton(app.ButtonGroup_4);
            app.NoButton_2.Text = 'No';
            app.ShowTreeonMapLabel = uilabel(app.UIFigure);
            app.ShowTreeonMapLabel.Text = 'Show Tree on Map';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Tree_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end